classdef proyectoFinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModificardatosButton            matlab.ui.control.Button
        CalculadoradeprobabilidadPanel  matlab.ui.container.Panel
        ProbabilidaddentrarLabel        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        calcularButton                  matlab.ui.control.Button
        PuntosGREEditField              matlab.ui.control.NumericEditField
        PuntosGREEditFieldLabel         matlab.ui.control.Label
        EcuacindelarectaPanel           matlab.ui.container.Panel
        labelModelo                     matlab.ui.control.Label
        miTabla                         matlab.ui.control.Table
        miGrafica                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Obtenemos los datos
            datos = readtable('Admission_Predict.csv');

            % Tomamos GREScore y ChanceOfAdmit
            columnasUsadas = {'GREScore', 'ChanceOfAdmit'};
            datos = datos(1:400, columnasUsadas);
            app.miTabla.Data = datos; % Ya tiene formato de tabla
%    app.miTabla.BackgroundColor = [1 1 1; 0 1 0]; Colores (se ven feos)

            % Para graficar
            x = table2array( datos(1:400,'GREScore') );
            y = table2array( datos(1:400,'ChanceOfAdmit') );


            plot(app.miGrafica,x, y,'.'); % * . X p h o + s d
            hold(app.miGrafica,'on');
%             % R E G R E S I Ó N (Hay que investigar cómo funciona)
            coeficientes = polyfit(x, y, 1);

            % Obtener los coeficientes de la recta
            pendiente = coeficientes(1);
            ordenada = coeficientes(2);
%             recta_regresion = pendiente * x + ordenada;
%             plot(app.miGrafica, y, 'o', x, recta_regresion, '-');
             plot(app.miGrafica, x, pendiente*x + ordenada);


             % M O D E L O
             app.labelModelo.Text = sprintf("y = %f X %f", pendiente,ordenada);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.miGrafica = uiaxes(app.UIFigure);
            title(app.miGrafica, 'Regresión lineal')
            xlabel(app.miGrafica, 'X')
            ylabel(app.miGrafica, 'Y')
            zlabel(app.miGrafica, 'Z')
            colormap(app.miGrafica, 'turbo')
            app.miTabla = uitable(app.UIFigure);
            app.miTabla.ColumnName = {'GREScore'; 'ChanceAdmit'};
            app.miTabla.RowName = {};
            app.EcuacindelarectaPanel = uipanel(app.UIFigure);
            app.EcuacindelarectaPanel.Title = 'Ecuación de la recta';
            app.labelModelo = uilabel(app.EcuacindelarectaPanel);
            app.labelModelo.Text = 'y = mx + b';
            app.CalculadoradeprobabilidadPanel = uipanel(app.UIFigure);
            app.CalculadoradeprobabilidadPanel.Title = 'Calculadora de probabilidad';
            app.PuntosGREEditFieldLabel = uilabel(app.CalculadoradeprobabilidadPanel);
            app.PuntosGREEditFieldLabel.Text = 'Puntos GRE';
            app.PuntosGREEditField = uieditfield(app.CalculadoradeprobabilidadPanel, 'numeric');
            app.calcularButton = uibutton(app.CalculadoradeprobabilidadPanel, 'push');
            app.calcularButton.Text = 'calcular';
            app.Label = uilabel(app.CalculadoradeprobabilidadPanel);
            app.Label.Text = '';
            app.ProbabilidaddentrarLabel = uilabel(app.CalculadoradeprobabilidadPanel);
            app.ProbabilidaddentrarLabel.Text = 'Probabilidad de ntrar';
            app.ModificardatosButton = uibutton(app.UIFigure, 'push');
            app.ModificardatosButton.Text = 'Modificar datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyectoFinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end